classdef Mini_Photoshop_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        MiniPhotoshopLabel         matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        TabGroup                   matlab.ui.container.TabGroup
        DetailTab                  matlab.ui.container.Tab
        BrowseImageButton          matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
        Panel_2                    matlab.ui.container.Panel
        DetailImageLabel           matlab.ui.control.Label
        TitleLabel                 matlab.ui.control.Label
        TitleEditField             matlab.ui.control.EditField
        PathfileEditFieldLabel     matlab.ui.control.Label
        PathfileEditField          matlab.ui.control.EditField
        WidthEditFieldLabel        matlab.ui.control.Label
        WidthEditField             matlab.ui.control.NumericEditField
        pxLabel                    matlab.ui.control.Label
        pxLabel_2                  matlab.ui.control.Label
        HeightEditFieldLabel       matlab.ui.control.Label
        HeightEditField            matlab.ui.control.NumericEditField
        ExitButton                 matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        ToolsTab                   matlab.ui.container.Tab
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        Panel_3                    matlab.ui.container.Panel
        ComplementButton           matlab.ui.control.Button
        GrayscaleButton            matlab.ui.control.Button
        RotationButton             matlab.ui.control.Button
        TranslationButton          matlab.ui.control.Button
        FlipButton                 matlab.ui.control.Button
        ZoominButton               matlab.ui.control.Button
        SaltPepperButton           matlab.ui.control.Button
        ZoomoutButton              matlab.ui.control.Button
        SaveImageButton_6          matlab.ui.control.Button
        BrightnessSliderLabel      matlab.ui.control.Label
        BrightnessSlider           matlab.ui.control.Slider
        MergeTab                   matlab.ui.container.Tab
        MergeImageLabel            matlab.ui.control.Label
        SaveImageButton_3          matlab.ui.control.Button
        UIAxes_8                   matlab.ui.control.UIAxes
        UIAxes_9                   matlab.ui.control.UIAxes
        UIAxes_10                  matlab.ui.control.UIAxes
        BrowseFirstImageButton     matlab.ui.control.Button
        BrowseSecondImageButton    matlab.ui.control.Button
        MergeImageButton           matlab.ui.control.Button
        HistogramTab               matlab.ui.container.Tab
        UIAxes_7                   matlab.ui.control.UIAxes
        SaveImageButton_5          matlab.ui.control.Button
        HistogramImageLabel        matlab.ui.control.Label
        BooleanTab                 matlab.ui.container.Tab
        UIAxes_11                  matlab.ui.control.UIAxes
        UIAxes_12                  matlab.ui.control.UIAxes
        UIAxes_13                  matlab.ui.control.UIAxes
        BrowseFirstImageButton_2   matlab.ui.control.Button
        BrowseSecondImageButton_2  matlab.ui.control.Button
        SaveImageButton_7          matlab.ui.control.Button
        ANDButton                  matlab.ui.control.Button
        ORButton                   matlab.ui.control.Button
        NOTButton                  matlab.ui.control.Button
        XORButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        Property2 % Description
        Property3 % Description
        Property4 % Description
        Property5 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            %tab detail
            [filename, pathname] = uigetfile('*.*');
            drawnow
            figure(app.UIFigure)
            if ~isequal(filename,0)
                Img=imread(fullfile(pathname,filename));
                imshow(Img,'Parent',app.UIAxes)
                title(app.UIAxes,'Original image')
                %original image pada tab tools
                imshow(Img,'Parent',app.UIAxes_3)
                title(app.UIAxes_3,'Original image')
                [a,b]=size(Img);
                %public property 1
                app.Property.Img=Img;
                %edit detail image
                app.TitleEditField.Value=filename;
                app.PathfileEditField.Value=pathname;
                app.HeightEditField.Value=a;
                app.WidthEditField.Value=b;
               
                %histogram
                [~,~,m] = size(Img);
                if m == 3
                    Img=app.Property.Img;
                    R = Img(:,:,1);
                    G = Img(:,:,2);
                    B = Img(:,:,3);
                    h=histogram(R(:),'Parent',app.UIAxes_7);
                    h.FaceColor=[1 0 0];
                    title(app.UIAxes_7,'Histogram')
                    xlim(app.UIAxes_7,[0 255])
                    ylim(app.UIAxes_7,[0 30000])
                    xticks(app.UIAxes_7,0:50:250)
                    yticks(app.UIAxes_7,0:5000:30000)
                    xlabel(app.UIAxes_7,'Pixel Intensity')
                    ylabel(app.UIAxes_7,'Frequency')
                    hold(app.UIAxes_7,"on")
                    h=histogram(G(:),'Parent',app.UIAxes_7);
                    h.FaceColor=[0 1 0];
                    h=histogram(B(:),'Parent',app.UIAxes_7);
                    h.FaceColor=[0 0 1];
                    hold(app.UIAxes_7,"off")
                else
                    gray=Img(:,256);
                    h = histogram(gray,'Parent',app.UIAxes_7);
                    h.FaceColor = [0.5 0.5 0.5];
                    h.EdgeColor = [0.5 0.5 0.5];
                    xlim(app.UIAxes_7,[0 255])
                    ylim(app.UIAxes_7,[0 300])
                    xlabel(app.UIAxes_7,'Pixel Intensity')
                    ylabel(app.UIAxes_7,'Frequency')
                end
            else
                return
            end
        end

        % Button pushed function: ComplementButton
        function ComplementButtonPushed(app, event)
            Img=app.Property.Img;
            [~,~,m] = size(Img);
                if m == 3
                    Img_Comp = imcomplement(Img);
                    imshow(Img_Comp,'Parent',app.UIAxes_2)
                    title(app.UIAxes_2,'Image Complement')
                else
                    Gray_Comp = imcomplement(Img);
                    imshow(Gray_Comp,'Parent',app.UIAxes_2)
                    title(app.UIAxes_2,'Image Complement')
                end
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
            Img=app.Property.Img;
            [~,~,m] = size(Img);
                if m == 3
                    Img_gs = rgb2gray(Img);
                    imshow(Img_gs,'Parent',app.UIAxes_2)
                    title(app.UIAxes_2,'Grayscale Image')
                else 
%                     imshow(Img,'Parent',app.UIAxes_2)
%                     title(app.UIAxes_2,'Grayscale Image')
%                         title(app.UIAxes_2,'Grayscale Image')
%                     pixl = impixel(Img)
                    if ndims(Img) ==2 && islogical(Img) 
%                         uint8Image = uint8(255*Img);
                        Gray = rgb2gray(Img);
                        bw = im2bw(Gray,graythresh(Gray));
                        imshow(bw,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Grayscale Image')
                    else 
                        imshow(Img,'Parent',app.UIAxes_2)
                        title(app.UIAxes_2,'Grayscale Image')
                    end
                end
            
        end

        % Button pushed function: RotationButton
        function RotationButtonPushed(app, event)
            Img=app.Property.Img
            [~,~,m] = size(Img);
            if m == 3
                r = rot90(Img(:,:,1),1);
                g = rot90(Img(:,:,2),1);
                b = rot90(Img(:,:,3),1);
                Img_rot = cat(3,r,g,b);
                imshow(Img_rot,'Parent',app.UIAxes_2)
                title(app.UIAxes_2,'Rotation')
            else
                gray = imrotate(Img,90)
                imshow(gray,'Parent',app.UIAxes_2)
                title(app.UIAxes_2,'Rotation')
            end
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
        Img=app.Property.Img;
        value = app.BrightnessSlider.Value;
        Img_bright = Img + value;
        imshow(Img_bright,'Parent',app.UIAxes_2)
        title(app.UIAxes_2,'Image Brightness')
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            Img=app.Property.Img;
            noise_img=imnoise(Img, 'salt & pepper');
            imshow(noise_img,'Parent',app.UIAxes_2)
            title(app.UIAxes_2,'Salt & pepper Noise Image')
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            Img=app.Property.Img;
            Img_flip=flip(Img);
            imshow(Img_flip,'Parent',app.UIAxes_2)
            title(app.UIAxes_2,'Flipped Image')
        end

        % Button pushed function: TranslationButton
        function TranslationButtonPushed(app, event)
            Img=app.Property.Img;
            Img_flip=imtranslate(Img,[100,20]);
            imshow(Img_flip,'Parent',app.UIAxes_2)
            title(app.UIAxes_2,'Translated Image')
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            Img=app.Property.Img;
            [m,n]=size(Img);
            for i =1:2*m
                for j=1:2*n
                    p=i-floor(i/2);
                    q=j-floor(j/2);
                    zoomimg(i,j)=Img(p,q);
                end
            end
%             [m,n] = size(Img);
%             k = 1;
%             l=1;
%             for i = 1:m
%                 for j = 1:2
%                     for h = 1:n
%                         for j = 1:2
%                             Zoom_img(k,l) = Img(i,j);
%                             l = l+1;
%                         end
%                     end
%                     l=1; 
%                     k=k+1;
%                 end
%             end
            imshow(zoomimg,'Parent',app.UIAxes_2)
            title(app.UIAxes_2,'Zoom in Image')
        end

        % Button pushed function: BrowseFirstImageButton
        function BrowseFirstImageButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.*');
            drawnow
            figure(app.UIFigure)
            if ~isequal(filename,0)
                Img_3=imread(fullfile(pathname,filename));
                imshow(Img_3,'Parent',app.UIAxes_8)
                title(app.UIAxes_8,'Original First Image')
                %detail image
                app.Property.Img_3=Img_3;
            else
                return
            end
        end

        % Button pushed function: BrowseSecondImageButton
        function BrowseSecondImageButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.*');
            drawnow
            figure(app.UIFigure)
            if ~isequal(filename,0)
                Img_2=imread(fullfile(pathname,filename));
                imshow(Img_2,'Parent',app.UIAxes_9)
                title(app.UIAxes_9,'Original Second Image')
                %detail image
                app.Property.Img_2=Img_2;
            else
                return
            end
        end

        % Button pushed function: MergeImageButton
        function MergeImageButtonPushed(app, event)
            Img_21=app.Property.Img_2;
            Img_31=app.Property.Img_3;
            [n,m]=size(Img_21);
            citra1=double(Img_21);
            citra2=double(Img_31);
            for i=1:n
                for j=1:m
                    merge_img(i,j)=citra1(i,j)+citra2(i,j);
                end
            end
            merge_img=uint8(merge_img);
            imshow(merge_img,'Parent',app.UIAxes_10)
            title(app.UIAxes_10,'Merge Image')
        end

        % Button pushed function: SaveImageButton_6
        function SaveImageButton_4Pushed(app, event)
            [filename, pathname]=uiputfile({'*.jpg';'*.png'}, 'Save Image As');
            aN = getimage(app.UIAxes_2);
            save = [pathname filename];
            imwrite(aN, save, 'jpg');
            msgbox('Image saved! ','Success');
        end

        % Button pushed function: SaveImageButton_3
        function SaveImageButton_3Pushed(app, event)
            [filename, pathname]=uiputfile({'*.jpg';'*.png'}, 'Save Image As');
            aN = getimage(app.UIAxes_6);
            save = [pathname filename];
            imwrite(aN, save, 'jpg');
            msgbox('Image saved! ','Success');
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            msgbox('Thanks for using Mini Photoshop by Elen Riswana (H92219048)');
            pause(1);
            close();
            close();
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)
            Img=app.Property.Img;
%             s = size(Img);
%             s1=s/2;
%             k=1;
%             l=1;
%             for i=1:s1
%                 for j=1:s1
%                     B(i,j)=Img(k,l);
%                     l=l+2;
%                 end
%                 l=1;
%                 k=k+2;
%             end
            [m,n] = size(Img);
            for i=1:m/2
                for j=1:n/2
                    p=i-floor(i/2);
                    q=j-floor(j/2);
                    c(i,j)=Img(p,q);
                end
            end
            imshow(c,'Parent',app.UIAxes_2)
            title(app.UIAxes_2,'Zoom out Image')
        end

        % Button pushed function: BrowseFirstImageButton_2
        function BrowseFirstImageButton_2Pushed(app, event)
            [filename, pathname] = uigetfile('*.*');
            drawnow
            figure(app.UIFigure)
            if ~isequal(filename,0)
                Img_4=imread(fullfile(pathname,filename));
                imshow(Img_4,'Parent',app.UIAxes_11)
                title(app.UIAxes_11,'Original First Image')
                %detail image
                app.Property.Img_4=Img_4;
            end
        end

        % Button pushed function: BrowseSecondImageButton_2
        function BrowseSecondImageButton_2Pushed(app, event)
            [filename, pathname] = uigetfile('*.*');
            drawnow
            figure(app.UIFigure)
            if ~isequal(filename,0)
                Img_5=imread(fullfile(pathname,filename));
                imshow(Img_5,'Parent',app.UIAxes_12)
                title(app.UIAxes_12,'Original Second Image')
                %detail image
                app.Property.Img_5=Img_5;
            end
        end

        % Button pushed function: ANDButton
        function ANDButtonPushed(app, event)
            Img_4=app.Property.Img_4;
            Img_5=app.Property.Img_5;
            and_img=bitand(Img_4,Img_5);
            imshow(and_img,'Parent',app.UIAxes_13)
            title(app.UIAxes_13,'AND')
        end

        % Button pushed function: ORButton
        function ORButtonPushed(app, event)
            Img_4=app.Property.Img_4;
            Img_5=app.Property.Img_5;
            or_img=bitor(Img_4,Img_5);
            imshow(or_img,'Parent',app.UIAxes_13)
            title(app.UIAxes_13,'OR')
        end

        % Button pushed function: NOTButton
        function NOTButtonPushed(app, event)
            Img_4=app.Property.Img_4;
            Img_5=app.Property.Img_5;
            or_img=bitor(Img_4,Img_5);
            nor_img=bitcmp(or_img);
            imshow(nor_img,'Parent',app.UIAxes_13)
            title(app.UIAxes_13,'NOT')
        end

        % Button pushed function: XORButton
        function XORButtonPushed(app, event)
            Img_4=app.Property.Img_4;
            Img_5=app.Property.Img_5;
            or_img=bitxor(Img_4,Img_5);
            xor_img=bitcmp(or_img)
            imshow(xor_img,'Parent',app.UIAxes_13)
            title(app.UIAxes_13,'XOR')
        end

        % Button pushed function: SaveImageButton_5
        function SaveImageButton_5Pushed(app, event)
            [filename, pathname]=uiputfile({'*.jpg';'*.png'}, 'Save Image As');
            aN = getimage(app.UIAxes_7);
            save = [pathname filename];
            imwrite(aN, save, 'jpg');
            msgbox('Image saved! ','Success');
        end

        % Button pushed function: SaveImageButton_7
        function SaveImageButton_7Pushed(app, event)
            [filename, pathname]=uiputfile({'*.jpg';'*.png'}, 'Save Image As');
            aN = getimage(app.UIAxes_13);
            save = [pathname filename];
            imwrite(aN, save, 'jpg');
            msgbox('Image saved! ','Success');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes,'reset')
            cla(app.UIAxes_3,'reset')
            cla(app.UIAxes_7,'reset')
            app.TitleEditField.Value='';
            app.PathfileEditField.Value='';
            app.WidthEditField.Value=0;
            app.HeightEditField.Value=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.MiniPhotoshopLabel = uilabel(app.Panel);
            app.MiniPhotoshopLabel.Text = 'Mini Photoshop';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'ps_logo.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DetailTab = uitab(app.TabGroup);
            app.DetailTab.Title = 'Detail';
            app.BrowseImageButton = uibutton(app.DetailTab, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.Image = uiimage(app.DetailTab);
            app.Image.ImageSource = 'browse-removebg-preview.png';
            app.UIAxes = uiaxes(app.DetailTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Panel_2 = uipanel(app.DetailTab);
            app.Panel_2.Title = 'Panel';
            app.DetailImageLabel = uilabel(app.Panel_2);
            app.DetailImageLabel.Text = 'Detail Image';
            app.TitleLabel = uilabel(app.Panel_2);
            app.TitleLabel.Text = 'Title      :';
            app.TitleEditField = uieditfield(app.Panel_2, 'text');
            app.PathfileEditFieldLabel = uilabel(app.Panel_2);
            app.PathfileEditFieldLabel.Text = 'Pathfile :';
            app.PathfileEditField = uieditfield(app.Panel_2, 'text');
            app.WidthEditFieldLabel = uilabel(app.Panel_2);
            app.WidthEditFieldLabel.Text = 'Width :';
            app.WidthEditField = uieditfield(app.Panel_2, 'numeric');
            app.pxLabel = uilabel(app.Panel_2);
            app.pxLabel.Text = 'px';
            app.pxLabel_2 = uilabel(app.Panel_2);
            app.pxLabel_2.Text = 'px';
            app.HeightEditFieldLabel = uilabel(app.Panel_2);
            app.HeightEditFieldLabel.Text = 'Height   :';
            app.HeightEditField = uieditfield(app.Panel_2, 'numeric');
            app.ExitButton = uibutton(app.DetailTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ResetButton = uibutton(app.DetailTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.UIAxes_2 = uiaxes(app.ToolsTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.ToolsTab);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.Panel_3 = uipanel(app.ToolsTab);
            app.ComplementButton = uibutton(app.Panel_3, 'push');
            app.ComplementButton.ButtonPushedFcn = createCallbackFcn(app, @ComplementButtonPushed, true);
            app.ComplementButton.Text = 'Complement';
            app.GrayscaleButton = uibutton(app.Panel_3, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale';
            app.RotationButton = uibutton(app.Panel_3, 'push');
            app.RotationButton.ButtonPushedFcn = createCallbackFcn(app, @RotationButtonPushed, true);
            app.RotationButton.Text = 'Rotation';
            app.TranslationButton = uibutton(app.Panel_3, 'push');
            app.TranslationButton.ButtonPushedFcn = createCallbackFcn(app, @TranslationButtonPushed, true);
            app.TranslationButton.Text = 'Translation';
            app.FlipButton = uibutton(app.Panel_3, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Text = 'Flip';
            app.ZoominButton = uibutton(app.Panel_3, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.SaltPepperButton = uibutton(app.Panel_3, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.ZoomoutButton = uibutton(app.Panel_3, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.SaveImageButton_6 = uibutton(app.Panel_3, 'push');
            app.SaveImageButton_6.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButton_4Pushed, true);
            app.SaveImageButton_6.Icon = 'ps_save.png';
            app.SaveImageButton_6.Text = 'Save Image';
            app.BrightnessSliderLabel = uilabel(app.Panel_3);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Panel_3);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.MergeTab = uitab(app.TabGroup);
            app.MergeTab.Title = 'Merge';
            app.MergeImageLabel = uilabel(app.MergeTab);
            app.MergeImageLabel.Text = 'Merge Image';
            app.SaveImageButton_3 = uibutton(app.MergeTab, 'push');
            app.SaveImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButton_3Pushed, true);
            app.SaveImageButton_3.Icon = 'ps_save.png';
            app.SaveImageButton_3.Text = 'Save Image';
            app.UIAxes_8 = uiaxes(app.MergeTab);
            title(app.UIAxes_8, '')
            xlabel(app.UIAxes_8, '')
            ylabel(app.UIAxes_8, '')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_9 = uiaxes(app.MergeTab);
            title(app.UIAxes_9, '')
            xlabel(app.UIAxes_9, '')
            ylabel(app.UIAxes_9, '')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.MergeTab);
            title(app.UIAxes_10, '')
            xlabel(app.UIAxes_10, '')
            ylabel(app.UIAxes_10, '')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.BrowseFirstImageButton = uibutton(app.MergeTab, 'push');
            app.BrowseFirstImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFirstImageButtonPushed, true);
            app.BrowseFirstImageButton.Text = 'Browse First Image ';
            app.BrowseSecondImageButton = uibutton(app.MergeTab, 'push');
            app.BrowseSecondImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSecondImageButtonPushed, true);
            app.BrowseSecondImageButton.Text = 'Browse Second Image';
            app.MergeImageButton = uibutton(app.MergeTab, 'push');
            app.MergeImageButton.ButtonPushedFcn = createCallbackFcn(app, @MergeImageButtonPushed, true);
            app.MergeImageButton.Text = 'Merge Image';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.UIAxes_7 = uiaxes(app.HistogramTab);
            title(app.UIAxes_7, '')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, '')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.SaveImageButton_5 = uibutton(app.HistogramTab, 'push');
            app.SaveImageButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButton_5Pushed, true);
            app.SaveImageButton_5.Icon = 'ps_save.png';
            app.SaveImageButton_5.Text = 'Save Image';
            app.HistogramImageLabel = uilabel(app.HistogramTab);
            app.HistogramImageLabel.Text = 'Histogram Image';
            app.BooleanTab = uitab(app.TabGroup);
            app.BooleanTab.Title = 'Boolean';
            app.UIAxes_11 = uiaxes(app.BooleanTab);
            title(app.UIAxes_11, '')
            xlabel(app.UIAxes_11, '')
            ylabel(app.UIAxes_11, '')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.BooleanTab);
            title(app.UIAxes_12, '')
            xlabel(app.UIAxes_12, '')
            ylabel(app.UIAxes_12, '')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.UIAxes_13 = uiaxes(app.BooleanTab);
            title(app.UIAxes_13, '')
            xlabel(app.UIAxes_13, '')
            ylabel(app.UIAxes_13, '')
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.BrowseFirstImageButton_2 = uibutton(app.BooleanTab, 'push');
            app.BrowseFirstImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseFirstImageButton_2Pushed, true);
            app.BrowseFirstImageButton_2.Text = 'Browse First Image ';
            app.BrowseSecondImageButton_2 = uibutton(app.BooleanTab, 'push');
            app.BrowseSecondImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseSecondImageButton_2Pushed, true);
            app.BrowseSecondImageButton_2.Text = 'Browse Second Image';
            app.SaveImageButton_7 = uibutton(app.BooleanTab, 'push');
            app.SaveImageButton_7.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButton_7Pushed, true);
            app.SaveImageButton_7.Icon = 'ps_save.png';
            app.SaveImageButton_7.Text = 'Save Image';
            app.ANDButton = uibutton(app.BooleanTab, 'push');
            app.ANDButton.ButtonPushedFcn = createCallbackFcn(app, @ANDButtonPushed, true);
            app.ANDButton.Text = 'AND';
            app.ORButton = uibutton(app.BooleanTab, 'push');
            app.ORButton.ButtonPushedFcn = createCallbackFcn(app, @ORButtonPushed, true);
            app.ORButton.Text = 'OR';
            app.NOTButton = uibutton(app.BooleanTab, 'push');
            app.NOTButton.ButtonPushedFcn = createCallbackFcn(app, @NOTButtonPushed, true);
            app.NOTButton.Text = 'NOT';
            app.XORButton = uibutton(app.BooleanTab, 'push');
            app.XORButton.ButtonPushedFcn = createCallbackFcn(app, @XORButtonPushed, true);
            app.XORButton.Text = 'XOR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mini_Photoshop_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end